classdef robotbartenderAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        StartButton            matlab.ui.control.Button
        ClearButton            matlab.ui.control.Button
        ResetButton            matlab.ui.control.Button
        ontherocksButton       matlab.ui.control.Button
        shakennotstiredButton  matlab.ui.control.Button
        RumandcokeButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        robots % Description
        objects % Description
        environment % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            [app.robots, app.objects,app.environment] = setup();
        end

        % Button pushed function: RumandcokeButton
        function RumandcokeButtonPushed(app, event)
            stirredRumAndCoke([app.robots(1), ...
                app.robots(2)], ...
                [app.objects(8), ...
                app.objects(7), ...
                app.objects(9), ...
                app.objects(3), ...
                app.objects(5)], ...
                app.environment);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            close all
            set(0,'DefaultFigureWindowStyle','docked')
            clear
            clc
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            for i = 1:size(app.robots, 2)
                app.robots(i).home;
            end
        end

        % Button pushed function: ontherocksButton
        function ontherocksButtonPushed(app, event)
            onTheRocks([app.robots(2), ...
                app.robots(1)], ...
                [app.objects(1), ...
                app.objects(3)], ...
                app.environment);
        end

        % Button pushed function: shakennotstiredButton
        function shakennotstiredButtonPushed(app, event)
            shakenNotstired([app.robots(1), ...
                app.robots(2)], ...
                [app.objects(2), ...
                app.objects(3), ...
                app.objects(6)], ...
                app.environment);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ontherocksButton = uibutton(app.UIFigure, 'push');
            app.ontherocksButton.ButtonPushedFcn = createCallbackFcn(app, @ontherocksButtonPushed, true);
            app.ontherocksButton.Text = 'on the rocks';
            app.shakennotstiredButton = uibutton(app.UIFigure, 'push');
            app.shakennotstiredButton.ButtonPushedFcn = createCallbackFcn(app, @shakennotstiredButtonPushed, true);
            app.shakennotstiredButton.Text = 'shaken not stired';
            app.RumandcokeButton = uibutton(app.UIFigure, 'push');
            app.RumandcokeButton.ButtonPushedFcn = createCallbackFcn(app, @RumandcokeButtonPushed, true);
            app.RumandcokeButton.Text = 'Rum and coke';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robotbartenderAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end